classdef miniporject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        plotButton              matlab.ui.control.StateButton
        ImportButton            matlab.ui.control.Button
        filenameEditField       matlab.ui.control.EditField
        filenameEditFieldLabel  matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function filenameEditFieldValueChanged(app, event)
            value = app.filenameEditField.Value;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename]=uigetfile();
            app.filenameEditField.Value = filename;
            figure(app.UIFigure);
            [data, Fs] = audioread(app.filenameEditField.Value);
            t = linspace(0,length(data)/Fs,length(data));
            plot(app.UIAxes,t,data)
        end

        % Value changed function: plotButton
        function plotButtonValueChanged(app, event)
            %value = app.plotButton.Value;
            [data, Fs] = audioread(app.filenameEditField.Value)
            t = linspace(0,length(data)/Fs,length(data));
            data_fft = fft(data);
            %plot(app.UIAxes2,data_fft)
            plot(app.UIAxes2,abs(data_fft(:,1)))

            %load handel.mat

            %filename1 = 'handel.wav';
            %audiowrite(filename1,data_fft,Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '1kHz-fft')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name :';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.plotButton = uibutton(app.UIFigure, 'state');
            app.plotButton.ValueChangedFcn = createCallbackFcn(app, @plotButtonValueChanged, true);
            app.plotButton.Text = 'plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniporject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end